classdef StarIDGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        Panel                matlab.ui.container.Panel
        UIAxes               matlab.ui.control.UIAxes
        DisconnectButton     matlab.ui.control.Button
        GetImageButton       matlab.ui.control.Button
        StarIDLamp           matlab.ui.control.Lamp
        StarIDLampLabel      matlab.ui.control.Label
        ConnectLamp          matlab.ui.control.Lamp
        ConnectLampLabel     matlab.ui.control.Label
        LogTextArea          matlab.ui.control.TextArea
        SaveLogButton        matlab.ui.control.Button
        StartStarIDButton    matlab.ui.control.Button
        ServerConnectButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        Property; % Description
        client;
        StarMat;
        pixX;
        pixY;
        Threshold = 20;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ServerConnectButton
        function ServerConnectButtonPushed(app, event)
            if isa(app.client,'tcpclient') == 0
                app.client = TCPClient('localhost', 10000);
            end
            lamptemp = isa(app.client,'tcpclient');
            if lamptemp == 1
                app.ConnectLamp.Color = [0 1 0];
            end
        end

        % Button pushed function: StartStarIDButton
        function StartStarIDButtonPushed(app, event)
            if isa(app.client,'tcpclient') == 1
                main_StarID(app.StarMat,app.Threshold,app.UIAxes);
            end

        end

        % Button pushed function: GetImageButton
        function GetImageButtonPushed(app, event)
            if isa(app.client,'tcpclient') == 1 && app.client.NumBytesAvailable > 0
                cla(app.UIAxes,'reset');
                [app.StarMat, app.pixX, app.pixY] = TCPIPReadData(app.client);     
                imshow(app.StarMat, 'parent', app.UIAxes);
           end
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            if isa(app.client,'tcpclient') == 1
                clear app.client;
            end
            lamptemp = isa(app.client,'tcpclient');
            if lamptemp == 0
                app.ConnectLamp.Color = [1 0 0];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ServerConnectButton = uibutton(app.GridLayout, 'push');
            app.ServerConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ServerConnectButtonPushed, true);
            app.ServerConnectButton.Text = 'ServerConnect';
            app.StartStarIDButton = uibutton(app.GridLayout, 'push');
            app.StartStarIDButton.ButtonPushedFcn = createCallbackFcn(app, @StartStarIDButtonPushed, true);
            app.StartStarIDButton.Text = 'Start StarID';
            app.SaveLogButton = uibutton(app.GridLayout, 'push');
            app.SaveLogButton.Text = 'Save Log';
            app.LogTextArea = uitextarea(app.GridLayout);
            app.ConnectLampLabel = uilabel(app.GridLayout);
            app.ConnectLampLabel.Text = 'Connect';
            app.ConnectLamp = uilamp(app.GridLayout);
            app.StarIDLampLabel = uilabel(app.GridLayout);
            app.StarIDLampLabel.Text = 'StarID';
            app.StarIDLamp = uilamp(app.GridLayout);
            app.GetImageButton = uibutton(app.GridLayout, 'push');
            app.GetImageButton.ButtonPushedFcn = createCallbackFcn(app, @GetImageButtonPushed, true);
            app.GetImageButton.Text = 'GetImage';
            app.DisconnectButton = uibutton(app.GridLayout, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.Panel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.Panel);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.UIAxes.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StarIDGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end